classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        ClassificationButton    matlab.ui.control.Button
        SegmentButton           matlab.ui.control.Button
        HelpButton              matlab.ui.control.Button
        AdvancedSettingsButton  matlab.ui.control.Button
        UploadImageButton       matlab.ui.control.Button
        UIAxes_2                matlab.ui.control.UIAxes
        UIAxes                  matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
   
    img
    preprocessed_img
    segmented_img
    classified_img
    wavelet = 'db3'; % Default wavelet function
    level = 2;       % Default decomposition level
    UIAxes1              matlab.ui.control.UIAxes
    UIAxes3              matlab.ui.control.UIAxes

 % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: UploadImageButton
        function UploadImageButtonPushed(app, event)
            [file, path] = uigetfile({'*.png;*.jpg;*.tif'}, 'Select an Image');
            if isequal(file, 0)
                uialert(app.UIFigure, 'No image selected!', 'Error');
                return;
            end
            app.img = imread(fullfile(path, file));
            if size(app.img, 3) == 3
                app.img = rgb2gray(app.img);
            end
            app.img = im2double(app.img);
            imshow(app.img, 'Parent', app.UIAxes1)
        end

        % Button pushed function: SegmentButton
        function SegmentButtonPushed(app, event)
            if isempty(app.preprocessed_img)
                uialert(app.UIFigure, 'Please pre-process the image first!', 'Error');
                return;
            end
            app.segmented_img = Mask_RCNN_Segment(app.preprocessed_img);
            imshow(app.segmented_img, 'Parent', app.UIAxes3);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Original Image')
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.UIAxes_2 = uiaxes(app.UIFigure);
            title(app.UIAxes_2, 'Segmented Image')
            app.UIAxes_2.XTick = [];
            app.UIAxes_2.YTick = [];
            app.UploadImageButton = uibutton(app.UIFigure, 'push');
            app.UploadImageButton.ButtonPushedFcn = createCallbackFcn(app, @UploadImageButtonPushed, true);
            app.UploadImageButton.Text = 'Upload Image';
            app.AdvancedSettingsButton = uibutton(app.UIFigure, 'push');
            app.AdvancedSettingsButton.Text = 'Advanced Settings';
            app.HelpButton = uibutton(app.UIFigure, 'push');
            app.HelpButton.Text = 'Help (?)';
            app.SegmentButton = uibutton(app.UIFigure, 'push');
            app.SegmentButton.ButtonPushedFcn = createCallbackFcn(app, @SegmentButtonPushed, true);
            app.SegmentButton.Text = 'Segment';
            app.ClassificationButton = uibutton(app.UIFigure, 'push');
            app.ClassificationButton.Text = 'Classification';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end